classdef GUI_SILS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimualtionSetupTab             matlab.ui.container.Tab
        AutomationWindPanel            matlab.ui.container.Panel
        AutoVarianceField              matlab.ui.control.NumericEditField
        VarianceEditField_2Label       matlab.ui.control.Label
        AutoMaxOffsetField             matlab.ui.control.NumericEditField
        MaxOffsetEditFieldLabel_2      matlab.ui.control.Label
        AutoMaxWindField               matlab.ui.control.NumericEditField
        MaxWindSpeedAbsEditFieldLabel  matlab.ui.control.Label
        StartQuitButton                matlab.ui.control.StateButton
        SimulationModeDropDown         matlab.ui.control.DropDown
        WindInjectionPanel             matlab.ui.container.Panel
        WindInjection_ResetButton      matlab.ui.control.StateButton
        SingleSimWindPanel             matlab.ui.container.Panel
        WindInjection_EnterButton_Single  matlab.ui.control.StateButton
        MeanEditFieldLabel_7           matlab.ui.control.Label
        VarianceEditField_Y            matlab.ui.control.NumericEditField
        MeanEditFieldLabel_6           matlab.ui.control.Label
        VarianceEditField_X            matlab.ui.control.NumericEditField
        VarianceEditField_Z            matlab.ui.control.NumericEditField
        VarianceEditFieldLabel         matlab.ui.control.Label
        MeanEditFieldLabel_5           matlab.ui.control.Label
        MaxOffsetEditField_Y           matlab.ui.control.NumericEditField
        MeanEditFieldLabel_4           matlab.ui.control.Label
        MaxOffsetEditField_X           matlab.ui.control.NumericEditField
        MaxOffsetEditField_Z           matlab.ui.control.NumericEditField
        MaxOffsetEditFieldLabel        matlab.ui.control.Label
        MeanEditFieldLabel_3           matlab.ui.control.Label
        MeanEditField_Y                matlab.ui.control.NumericEditField
        MeanEditFieldLabel_2           matlab.ui.control.Label
        MeanEditField_X                matlab.ui.control.NumericEditField
        MeanEditField_Z                matlab.ui.control.NumericEditField
        MeanEditFieldLabel             matlab.ui.control.Label
        VarianceLabel_Z                matlab.ui.control.Label
        MaxOffsetLabel_Z               matlab.ui.control.Label
        MeanLabel_Z                    matlab.ui.control.Label
        WindDropDown                   matlab.ui.control.DropDown
        FaultInjectionPanel            matlab.ui.container.Panel
        FaultInjection_ResetButton     matlab.ui.control.StateButton
        FaultInjection_EnterButton     matlab.ui.control.StateButton
        M2Button                       matlab.ui.control.StateButton
        M6Button                       matlab.ui.control.StateButton
        M7Button                       matlab.ui.control.StateButton
        M5Button                       matlab.ui.control.StateButton
        M4Button                       matlab.ui.control.StateButton
        M8Button                       matlab.ui.control.StateButton
        M3Button                       matlab.ui.control.StateButton
        M1Button                       matlab.ui.control.StateButton
        Octo_H                         matlab.ui.control.Image
        ReallocationEnablePanel        matlab.ui.container.Panel
        EnableCheckBox                 matlab.ui.control.CheckBox
        DisableCheckBox                matlab.ui.control.CheckBox
        SetpointPanel                  matlab.ui.container.Panel
        WEditField_Orientation         matlab.ui.control.NumericEditField
        WEditFieldLabel                matlab.ui.control.Label
        ZEditField_Orientation         matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        YEditField_Orientation         matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        XEditField_Orientation         matlab.ui.control.NumericEditField
        XEditField_2Label              matlab.ui.control.Label
        OrientationLabel               matlab.ui.control.Label
        ZEditField_Position            matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField_Position            matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField_Position            matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        PositionLabel                  matlab.ui.control.Label
        SimulationModeDropDownLabel    matlab.ui.control.Label
        SimulationMonitoringTab        matlab.ui.container.Tab
        PositionLabel_Title            matlab.ui.control.Label
        LocalPositionLabel             matlab.ui.control.Label
        FeasiblyControllableMetricLabel  matlab.ui.control.Label
        PositionErrorLabel             matlab.ui.control.Label
        MotorOutputsPanel              matlab.ui.container.Panel
        MotorLabel                     matlab.ui.control.Label
        Octo_H_2                       matlab.ui.control.Image
        Motor2Axes                     matlab.ui.control.UIAxes
        Motor4Axes                     matlab.ui.control.UIAxes
        Motor8Axes                     matlab.ui.control.UIAxes
        Motor6Axes                     matlab.ui.control.UIAxes
        Motor7Axes                     matlab.ui.control.UIAxes
        Motor3Axes                     matlab.ui.control.UIAxes
        Motor1Axes                     matlab.ui.control.UIAxes
        Motor5Axes                     matlab.ui.control.UIAxes
        FCMWAxes                       matlab.ui.control.UIAxes
        PositionErrorAxes              matlab.ui.control.UIAxes
        KATECH_Logo                    matlab.ui.control.Image
        AAMFlightReliabilityAssessmentSimulatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)

        AutomationFolder 
        MatlabFolder     
        SimulinkModel
        start_script_path
        close_script_path
        model_path
        roscore_start_path
        roscore_kill_path

        MotorButtonOriginalColor;
        SelectedFaultMotors = [];

        InjectedFaultMotors = [];
        FaultInjectedColor = [0.6 0 0.0];

        PoseSubscriber 
        RCOutSubscriber 
        UpdateTimer 

        MotorBarObjects 
        RCOut_Min = 1000; 
        RCOut_Max = 2200; 

        PositionErrorLine 
        FCMWLine          
        TimeHistory       
        PositionErrorHistory 
        FCMWHistory       
        SimulationStartTime

    end


    % ---------------------------------------------------------------------------------
    % Custom Functions
    % ---------------------------------------------------------------------------------

    methods (Access = private)

        function setUI_Initial(app)
            sim_mode = app.SimulationModeDropDown.Value;

            % Simulation Setup Tab Initialize
            app.EnableCheckBox.Value = 0;
            app.DisableCheckBox.Value = 0;

            SetupTab_On(app);
            MonitorTab_Off(app);


            if strcmp(sim_mode, 'Single Simulation')
                app.AutoVarianceField.Value = 0;
                app.AutoMaxOffsetField.Value = 0;
                app.AutoMaxWindField.Value = 0;

                app.M1Button.Value = 0;
                app.M2Button.Value = 0;
                app.M3Button.Value = 0;
                app.M4Button.Value = 0;
                app.M5Button.Value = 0;
                app.M6Button.Value = 0;
                app.M7Button.Value = 0;
                app.M8Button.Value = 0;
                MotorButtonValueChanged(app);

                app.SingleSimWindPanel.Visible = 'on';
                app.AutomationWindPanel.Visible = 'off';
                app.WindInjectionPanel.Enable = 'off';
                app.FaultInjectionPanel.Enable = 'off';


                Wind_SingleSim(app);

                app.FaultInjection_EnterButton.Visible = 'on';
                app.WindInjection_EnterButton_Single.Visible = 'on';

            elseif strcmp(sim_mode, 'Automation')
                app.SingleSimWindPanel.Visible = 'off';
                app.AutomationWindPanel.Visible = 'on';

                app.WindInjection_EnterButton_Single.Visible = 'off';
                Wind_Automation(app);

                app.FaultInjection_EnterButton.Visible = 'off';

                try
                    evalin('base', 'clear(''STOP_AUTOMATION_FLAG'')');
                catch
                end
            end
            
            if ~isempty(app.PositionErrorLine) && isgraphics(app.PositionErrorLine)
                clearpoints(app.PositionErrorLine);
                xlim(app.PositionErrorAxes,'auto');
                ylim(app.PositionErrorAxes,'auto'); 
            end
            if ~isempty(app.FCMWLine) && isgraphics(app.FCMWLine)
                clearpoints(app.FCMWLine);
                xlim(app.FCMWAxes,'auto'); 
            end
            app.TimeHistory = [];
            app.PositionErrorHistory = [];
            app.FCMWHistory = [];
            app.SimulationStartTime = []; 
            % ----------------------
        end


        function Envvar_Init(app)
            % Setpoint Panel
            app.XEditField_Position.Value = 0;
            app.YEditField_Position.Value = 0;
            app.ZEditField_Position.Value = 20;
            app.XEditField_Orientation.Value = 0;
            app.YEditField_Orientation.Value = 0;
            app.ZEditField_Orientation.Value = 0;
            app.WEditField_Orientation.Value = 10;

            % Reallocation Panel
            app.EnableCheckBox.Value = 0;
            app.DisableCheckBox.Value = 0;
 
            if isprop(app, 'Reallocation_EnterButton') 
                app.Reallocation_EnterButton.Value = 0;
            end

            % WindInjectionPanel
            app.WindDropDown.Value = "Constant Wind";
            app.MeanEditField_X.Value = 0;
            app.MeanEditField_Y.Value = 0;
            app.MeanEditField_Z.Value = 0;
            app.VarianceEditField_X.Value = 0;
            app.VarianceEditField_Y.Value = 0;
            app.VarianceEditField_Z.Value = 0;
            app.MaxOffsetEditField_X.Value = 0;
            app.MaxOffsetEditField_Y.Value = 0;
            app.MaxOffsetEditField_Z.Value = 0;
            
            if isprop(app, 'AutoMaxWindField') 
                app.AutoMaxWindField.Value = 0;
                app.AutoVarianceField.Value = 0;
                app.AutoMaxOffsetField.Value = 0;
            end

            %FaultInjection Panel
            app.M1Button.Value = 0;
            app.M2Button.Value = 0;
            app.M3Button.Value = 0;
            app.M4Button.Value = 0;
            app.M5Button.Value = 0;
            app.M6Button.Value = 0;
            app.M7Button.Value = 0;
            app.M8Button.Value = 0;
        end


        function SetupTab_On(app)
            app.SimulationModeDropDown.Enable = 'on';
            app.SetpointPanel.Enable = 'on';
            app.ReallocationEnablePanel.Enable = 'on';
            app.WindInjectionPanel.Enable = 'on';
            app.FaultInjectionPanel.Enable = 'on';
            app.AutomationWindPanel.Enable = 'on';
        end

        function SetupTab_Off(app)
            app.SimulationModeDropDown.Enable = 'off';
            app.SetpointPanel.Enable = 'off';
            app.ReallocationEnablePanel.Enable = 'off';
            app.WindInjectionPanel.Enable = 'off';
            app.FaultInjectionPanel.Enable = 'off';
            app.AutomationWindPanel.Enable = 'off';
        end

        function MonitorTab_On(app)
            app.MotorOutputsPanel.Enable = 'on';
            app.LocalPositionLabel.Enable = 'on';
            app.PositionErrorLabel.Enable = 'on';
            app.FeasiblyControllableMetricLabel.Enable = 'on';
        end

        function MonitorTab_Off(app)
            app.MotorOutputsPanel.Enable = 'off';
            app.LocalPositionLabel.Enable = 'off';
            app.PositionErrorLabel.Enable = 'off';
            app.FeasiblyControllableMetricLabel.Enable = 'off';
        end

        function ReallocationBoxChecked(app)
            if strcmp(app.SimulationModeDropDown.Value, 'Single Simulation')
                app.ReallocationEnablePanel.Enable = 'off';

                app.WindInjectionPanel.Enable = 'on';
                app.SetpointPanel.Enable = 'off';
                app.FaultInjectionPanel.Enable = 'on';

            elseif strcmp(app.SimulationModeDropDown.Value, 'Automation')

            end

            
        end

        function Wind_SingleSim(app)
            if app.WindDropDown.Value == "Constant Wind"
                app.MeanEditField_X.Enable = "on";
                app.MeanEditField_Y.Enable = "on";
                app.MeanEditField_Z.Enable = "off";

                app.VarianceEditField_X.Enable = "off";
                app.VarianceEditField_Y.Enable = "off";
                app.VarianceEditField_Z.Enable = "off";
                app.MaxOffsetEditField_X.Enable = "off";
                app.MaxOffsetEditField_Y.Enable = "off";
                app.MaxOffsetEditField_Z.Enable = "off";

            elseif app.WindDropDown.Value == "Turbulence Wind"
                app.MeanEditField_X.Enable = "on";
                app.MeanEditField_Y.Enable = "on";
                app.MeanEditField_Z.Enable = "off";

                app.VarianceEditField_X.Enable = "on";
                app.VarianceEditField_Y.Enable = "on";
                app.VarianceEditField_Z.Enable = "off";
                app.MaxOffsetEditField_X.Enable = "on";
                app.MaxOffsetEditField_Y.Enable = "on";
                app.MaxOffsetEditField_Z.Enable = "off";
            end
        end

        function Wind_Automation(app)
            if app.WindDropDown.Value == "Constant Wind"
                app.AutoMaxWindField.Enable="on";
                app.AutoMaxOffsetField.Enable="off";
                app.AutoVarianceField.Enable="off";
            
            elseif app.WindDropDown.Value == "Turbulence Wind"
                app.AutoMaxWindField.Enable="on";
                app.AutoMaxOffsetField.Enable="on";
                app.AutoVarianceField.Enable="on";
            end
        end
        

        function Setpoint_Setenv(app)
            % --- Setpoint Position ---
            set_posX = app.XEditField_Position.Value;
            set_posY = app.YEditField_Position.Value;
            set_posZ = app.ZEditField_Position.Value;
            setenv('SETPOINT_X', num2str(set_posX));
            setenv('SETPOINT_Y', num2str(set_posY));
            setenv('SETPOINT_Z', num2str(set_posZ));
            % --- Setpoint Orientation ---
            set_oriX = app.XEditField_Orientation.Value;
            set_oriY = app.YEditField_Orientation.Value;
            set_oriZ = app.ZEditField_Orientation.Value;
            set_oriW = app.WEditField_Orientation.Value;
            setenv('SETPOINT_ORI_X', num2str(set_oriX));
            setenv('SETPOINT_ORI_Y', num2str(set_oriY));
            setenv('SETPOINT_ORI_Z', num2str(set_oriZ));
            setenv('SETPOINT_ORI_W', num2str(set_oriW));
        end

        function MotorButtonValueChanged(app)
            motorButtons = {app.M1Button, app.M2Button, app.M3Button, app.M4Button, ...
                app.M5Button, app.M6Button, app.M7Button, app.M8Button};

            selectedMotorsText = cell(1, 8);
            count = 0;

            originalColor = app.MotorButtonOriginalColor;
            injectedColor = app.FaultInjectedColor;
            selectedColor = [1 0 0]; 

            if ~(isnumeric(originalColor) && isequal(size(originalColor), [1 3]) && all(originalColor >= 0 & originalColor <= 1))
                originalColor = [0.94 0.94 0.94];
            end

            for i = 1:length(motorButtons)
                motorNum = str2double(motorButtons{i}.Text(2:end)); 
                
                if motorButtons{i}.Value == true 
                    count = count + 1;
                    selectedMotorsText{count} = motorButtons{i}.Text;
                    
                    if ismember(motorNum, app.InjectedFaultMotors)
                        motorButtons{i}.BackgroundColor = injectedColor; 
                    else
                        motorButtons{i}.BackgroundColor = selectedColor; 
                    end
                else 
                    motorButtons{i}.BackgroundColor = originalColor;
                end
            end
            selectedMotorsText = selectedMotorsText(1:count);


            selectedMotorNumbers = [];
            if ~isempty(selectedMotorsText)
                selectedMotorNumbers = sort(cellfun(@(x) str2double(x(2:end)), selectedMotorsText));
            end

            app.SelectedFaultMotors = selectedMotorNumbers;
            disp(['Selected Fault Motors: [', num2str(app.SelectedFaultMotors), ']']);
        end


        function SetInitialWindParams(app)
            modelName = app.SimulinkModel;
            wind_mode = app.WindDropDown.Value;


            try
                if strcmp(wind_mode, 'Constant Wind')
                    meanX = app.MeanEditField_X.Value; % 초기값 0
                    meanY = app.MeanEditField_Y.Value; % 초기값 0
                    % Z는 사용 안 함

                    set_param([modelName '/wind_toggle'], 'Value', '1');
                    set_param([modelName '/Switch Case Action Subsystem/wind_x2'], 'Value', num2str(meanX));
                    set_param([modelName '/Switch Case Action Subsystem/wind_y2'], 'Value', num2str(meanY));

                elseif strcmp(wind_mode, 'Turbulence Wind')
                    % 초기 상태에서는 Turbulence지만 값은 0으로 설정
                    input_wind_x = 0; % 초기값
                    input_wind_y = 0; % 초기값
                    wind_normal_distribution_Max = 0; % 초기값
                    wind_normal_distribution_Variance = 0; % 초기값

                    wind_normal_distribution_Direction_X = 0;
                    wind_normal_distribution_Direction_Y = 0;
                    wind_normal_distribution_X_Mean = 0;
                    wind_normal_distribution_X_Max = 0;
                    wind_normal_distribution_X_Variance = 0;
                    wind_normal_distribution_Y_Mean = 0;
                    wind_normal_distribution_Y_Max = 0;
                    wind_normal_distribution_Y_Variance = 0;

                    set_param([modelName '/wind_toggle'], 'Value', '2');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_X_Mean'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_X_Max'], 'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_X_Variance'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Y_Mean'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Y_Max'], 'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Y_Variance'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Dir_X'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Dir_Y'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Dir_Z'],'Value', '0');
                    disp('Initial Turbulence Wind parameters set to 0.');
                end
            catch ME_setparam
                warning('Failed to set initial Simulink wind parameters: %s', ME_setparam.message);
            end
        end


        function updateMonitoringUI(app)
            try
                latestPoseMsg = app.PoseSubscriber.LatestMessage;
                if ~isempty(latestPoseMsg)
                    posX = latestPoseMsg.Pose.Position.X;
                    posY = latestPoseMsg.Pose.Position.Y;
                    posZ = latestPoseMsg.Pose.Position.Z;
                    app.LocalPositionLabel.Text = sprintf('Local Pos: X: %.2f, Y: %.2f, Z: %.2f', posX, posY, posZ);
                else
                    app.LocalPositionLabel.Text = 'Local Pos: Waiting for /mavros/local_position/pose...';
                end

                latestRCOutMsg = app.RCOutSubscriber.LatestMessage;
                rcChannels = [];
                if ~isempty(latestRCOutMsg)
                    if (isstruct(latestRCOutMsg) && isfield(latestRCOutMsg, 'Channels')) || ...
                            (isobject(latestRCOutMsg) && isprop(latestRCOutMsg, 'Channels'))
                        rcChannels = latestRCOutMsg.Channels;
                    end
                end

                if ~isempty(rcChannels) && length(rcChannels) >= 8
                    app.MotorOutputsPanel.Title = 'Motor Outputs'; 
                    try
                        currentValues = double(rcChannels(1:8));
                        minVal = double(app.RCOut_Min);
                        maxVal = double(app.RCOut_Max);
                        normalizedValues = zeros(1, 8);
                        if maxVal > minVal
                            normalizedValues = (currentValues - minVal) / (maxVal - minVal);
                        else
                        end
                        normalizedValues(normalizedValues < 0) = 0;
                        normalizedValues(normalizedValues > 1) = 1;
                        for i = 1:8
                            barObj = app.MotorBarObjects{i};
                            if ~isempty(barObj) && isgraphics(barObj)
                                barObj.YData = normalizedValues(i);
                            end
                        end
                    catch ME_bar_update
                        disp(['Error updating individual bar graphs: ', ME_bar_update.message]);
                    end
                else
                    app.MotorOutputsPanel.Title = 'Motor Outputs (Waiting for /mavros/rc/out)';
                    try
                        for i = 1:8
                            barObj = app.MotorBarObjects{i};
                            if ~isempty(barObj) && isgraphics(barObj)
                                barObj.YData = 0;
                            end
                        end
                    catch 
                    end
                end
            catch ME_ros
                app.LocalPositionLabel.Text = 'Local Pos: ROS Update Error';
                app.MotorOutputsPanel.Title = 'Motor Outputs: ROS Update Error';
            end

            % --- 2. Simulink Plot Updates ---
            if app.StartQuitButton.Value == true

                if isempty(app.SimulationStartTime)
                    app.PositionErrorLabel.Text = 'Position Error (Simulation starting...)';
                    app.FeasiblyControllableMetricLabel.Text = 'FCMW (Simulation starting...)';
                    return; % 함수 종료
                end

                % [FIX] Simulink 변수 읽기 시도
                try
                    % 값 읽기
                    position_error = evalin('base', 'position_error');
                    FCMW_saturated = evalin('base', 'FCMW_saturated');
                    currentTime = toc(app.SimulationStartTime); % 이제 안전하게 toc 실행

                    % [수정] 값 읽기 성공 시 레이블 복원
                    app.PositionErrorLabel.Text = 'Position Error';
                    app.FeasiblyControllableMetricLabel.Text = 'Feasibly Controllable Metric';

                    % --- 1. 데이터 히스토리 추가 ---
                    app.TimeHistory(end+1) = currentTime;
                    app.PositionErrorHistory(end+1) = position_error;
                    app.FCMWHistory(end+1) = FCMW_saturated;
                    
                    % --- 2. Animated Line에 새 점만 추가 ---
                    if ~isempty(app.PositionErrorLine) && isgraphics(app.PositionErrorLine)
                        addpoints(app.PositionErrorLine, currentTime, position_error);
                    end
                    if ~isempty(app.FCMWLine) && isgraphics(app.FCMWLine)
                        addpoints(app.FCMWLine, currentTime, FCMW_saturated);
                    end
                    
                    % --- 3. X축 범위 조절 (최근 8초) ---
                    startTimeWindow = max(0, currentTime - 8); 
                    endTimeWindow = currentTime;
                    if ~isempty(app.PositionErrorLine) && isgraphics(app.PositionErrorLine)
                        xlim(app.PositionErrorAxes, [startTimeWindow, endTimeWindow]);
                    end
                    if ~isempty(app.FCMWLine) && isgraphics(app.FCMWLine)
                        xlim(app.FCMWAxes, [startTimeWindow, endTimeWindow]);
                    end
                    
                    % --- 5. 화면 업데이트 ---
                    drawnow limitrate;
                
                catch ME_eval
                    if strcmp(ME_eval.identifier, 'MATLAB:UndefinedFunction') || contains(ME_eval.message, 'Unrecognized function or variable')
                        app.PositionErrorLabel.Text = 'Position Error (Waiting for Simulink variable...)';
                        app.FeasiblyControllableMetricLabel.Text = 'FCMW (Waiting for Simulink variable...)';
                    else
                        app.PositionErrorLabel.Text = 'Position Error (Simulink read error)';
                        app.FeasiblyControllableMetricLabel.Text = 'FCMW (Simulink read error)';
                    end
                end

            else
                app.PositionErrorLabel.Text = 'Position Error (Simulation stopped)';
                app.FeasiblyControllableMetricLabel.Text = 'FCMW (Simulation stopped)';
            end
        end


        function passAutomationParamsToWorkspace(app)            
            try
                % 1. Reallocation (FTC_toggle)
                if app.EnableCheckBox.Value == true
                    auto_FTC_toggle = 1; % Enable
                    disp('FTC_toggle: Enable');
                else
                    auto_FTC_toggle = 0; % Disable
                    disp('FTC_toggle: Disable');
                end
                assignin('base', 'auto_FTC_toggle', auto_FTC_toggle);
                

                % 2. Fault Injection (motor_failure_number, 1, 2)
                % SelectedFaultMotors 배열을 가져와서 0으로 패딩
                faults = app.SelectedFaultMotors;
                faults_to_pass = [faults, zeros(1, 5)]; % 넉넉하게 0 추가
                faults_to_pass = faults_to_pass(1:5); % 정확히 5개로 자름
                
                assignin('base', 'auto_fault_array', faults_to_pass);
                disp(['  fault_array = [', num2str(faults_to_pass), ']']);

                % 3. Wind Type (wind_type_toggle)
                if strcmp(app.WindDropDown.Value, 'Constant Wind')
                    auto_wind_type = 1;
                else % Turbulence Wind
                    auto_wind_type = 2;
                end
                assignin('base', 'auto_wind_type', auto_wind_type);
                disp(['  wind_type = ', num2str(auto_wind_type)]);

                % 4. Wind Parameters
                if auto_wind_type == 1 % Constant Wind
                    auto_wind_max_abs = app.AutoMaxWindField.Value;
                    assignin('base', 'auto_wind_max_abs', auto_wind_max_abs);
                    disp(['  auto_wind_max_abs = ', num2str(auto_wind_max_abs)]);
                    
                    % 스크립트 호환을 위해 나머지 변수도 0으로 전달
                    assignin('base', 'auto_wind_norm_max', 0);
                    assignin('base', 'auto_wind_norm_var', 0);

                else % Turbulence Wind
                    auto_wind_max_abs = app.AutoMaxWindField.Value;
                    auto_wind_norm_max = app.AutoMaxOffsetField.Value;
                    auto_wind_norm_var = app.AutoVarianceField.Value;
                    
                    assignin('base', 'auto_wind_max_abs', auto_wind_max_abs);
                    assignin('base', 'auto_wind_norm_max', auto_wind_norm_max);
                    assignin('base', 'auto_wind_norm_var', auto_wind_norm_var);
                    disp(['  wind_max_abs = ', num2str(auto_wind_max_abs)]);
                    disp(['  wind_norm_max = ', num2str(auto_wind_norm_max)]);
                    disp(['  wind_norm_var = ', num2str(auto_wind_norm_var)]);
                end
                
                disp('Parameters successfully assigned to base workspace.');

            catch ME_assign
                uialert(app.UIFigure, ['Failed to pass parameters to workspace: ' ME_assign.message], 'Automation Error');
            end
        end


        function motor_button_init(app)

                disp('Resetting motor buttons.');
                motorButtons = {app.M1Button, app.M2Button, app.M3Button, app.M4Button, ...
                    app.M5Button, app.M6Button, app.M7Button, app.M8Button};
                originalColor = app.MotorButtonOriginalColor;
                if ~(isnumeric(originalColor) && isequal(size(originalColor), [1 3]) && all(originalColor >= 0 & originalColor <= 1))
                    originalColor = [0.94 0.94 0.94]; % 기본 회색
                end
                for i = 1:length(motorButtons)
                    motorButtons{i}.BackgroundColor = originalColor;
                    motorButtons{i}.Value = 0; % 버튼 상태도 리셋
                end
                app.SelectedFaultMotors = []; % 선택 목록 비우기
                app.InjectedFaultMotors = []; % 주입된 모터 목록도 비움
                disp('Motor button colors and selections reset.');
            

        end


        function runCleanupLogic(app)
            
            sim_mode = app.SimulationModeDropDown.Value;

            close_scenario = sprintf('"%s"', app.close_script_path);
            system(close_scenario);
            
            if strcmp(sim_mode, 'Single Simulation')
                disp('Single Simulation mode: Resetting GUI via setUI_Initial.');
                setUI_Initial(app);
            else
                disp('Automation mode: Skipping GUI reset to preserve setup values.');
                SetupTab_On(app);
            end
            
            try
                evalin('base', 'clear(''STOP_AUTOMATION_FLAG'')');
                evalin('base', 'clear(''GUI_flag'')');
            catch
            end
        end

        function off_Simulink(app)
            disp('Attempting to stop Simulink model (if running)...');
            try
                simStatus = get_param(app.SimulinkModel, 'SimulationStatus');
                if strcmp(simStatus, 'running') || strcmp(simStatus, 'paused')
                    set_param(app.SimulinkModel, 'SimulationCommand', 'stop');
                    disp('Simulink model stop command sent.');
                else
                    disp('Simulink model is not currently running.');
                end
            catch ME
                disp(['Simulink stop error (ignoring): ', ME.message]);
            end

        end

        
        function kill_roscore(app)
            system(sprintf('"%s"', app.roscore_kill_path));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                pathToMLAPP = fileparts(mfilename('fullpath'));
                assetsFolder = fullfile(pathToMLAPP, 'GUI_assets');
                disp('Find Image!');
                
                app.KATECH_Logo.ImageSource = fullfile(assetsFolder, 'KATECH_Logo_cut.png');
                app.Octo_H.ImageSource = fullfile(assetsFolder, 'octo_H_image.png');
                app.Octo_H_2.ImageSource = fullfile(assetsFolder, 'octo_H_image.png');
                
            catch ME_Image
                uialert(app.UIFigure, ['Failed to set image path: ' ME_Image.message], 'Asset Error');
            end
            
            Envvar_Init(app);

            app_folder = fileparts(mfilename('fullpath'));

            app.AutomationFolder = app_folder;
            app.MatlabFolder = fullfile(app_folder, '..', 'KATECH_matlab');
            app.SimulinkModel = 'data_collection';
            app.start_script_path = fullfile(app.AutomationFolder, 'launch_sils.sh');
            app.close_script_path = fullfile(app_folder, 'close_scenario.sh');
            app.model_path = fullfile(app.MatlabFolder, [app.SimulinkModel, '.slx']);

            app.roscore_start_path = fullfile(app.AutomationFolder, 'start_roscore.sh');
            app.roscore_kill_path = fullfile(app.AutomationFolder, 'kill_roscore.sh');
            
            disp('Starting roscore via script...');


            system(sprintf('"%s"', app.roscore_start_path));
            pause(2);


            try
                if ros.internal.Global.isNodeActive
                    disp('Existing ROS node found. Shutting it down before re-initializing...');
                    rosshutdown;
                    pause(1);
                end
                warning('off', 'ros:mlros:util:NodeHostNotAssociatedWithNetworkInterface');
                rosinit; 

                disp('ROS network initialized successfully.');

            catch ME
                error_msg = sprintf('Failed to initialize ROS network.\nError: %s\nPlease ensure ROS Master (roscore) is running.', ME.message);
                uialert(app.UIFigure, error_msg, 'ROS Init Error', 'Modal', true);
            end


            try
                tempColor = app.M1Button.BackgroundColor;
                if isnumeric(tempColor) && isequal(size(tempColor), [1 3]) && all(tempColor >= 0 & tempColor <= 1)
                    app.MotorButtonOriginalColor = tempColor;
                else
                    warning('Could not read valid initial button color. Using default gray.');
                    app.MotorButtonOriginalColor = [0.94 0.94 0.94];
                end
            catch ME_color
                warning('Error reading initial button color. Using default gray. Error: %s', ME_color.identifier, ME_color.message);
                app.MotorButtonOriginalColor = [0.94 0.94 0.94]; % 기본 회색
            end

            try
                app.PoseSubscriber = rossubscriber('/mavros/local_position/pose', 'geometry_msgs/PoseStamped', 'DataFormat','struct');
                app.RCOutSubscriber = rossubscriber('/mavros/rc/out', 'mavros_msgs/RCOut', 'DataFormat','struct');
                disp('ROS Subscribers created.');
            catch ME_sub
                uialert(app.UIFigure, ['Failed to create ROS subscribers: ' ME_sub.message], 'ROS Subscriber Error');
            end

            try
                axesHandles = {app.Motor1Axes, app.Motor2Axes, app.Motor3Axes, app.Motor4Axes, ...
                    app.Motor5Axes, app.Motor6Axes, app.Motor7Axes, app.Motor8Axes};
                app.MotorBarObjects = cell(1, 8); 

                initialValue = 0;

                for i = 1:8
                    ax = axesHandles{i};
                    app.MotorBarObjects{i} = bar(ax, initialValue, 0.8); 

                    ylim(ax, [0 1]);
                    xticks(ax, []); 
                    yticks(ax, [0, 0.5, 1]); 
                    grid(ax, 'on');
                end

                disp('Individual motor output bar graphs initialized.');
            catch ME_bar
                uialert(app.UIFigure, ['Failed to create initial bar graphs: ' ME_bar.message], 'UI Error');
            end


            app.UpdateTimer = timer('ExecutionMode', 'fixedRate', ... 
                'Period', 0.5, ... 
                'TimerFcn', @(~,~) app.updateMonitoringUI); 
            start(app.UpdateTimer);
            disp('Monitoring UI update timer started.');

            try
                ax1 = app.PositionErrorAxes;
                app.PositionErrorLine = animatedline(ax1, 'Color', 'r');

                ax2 = app.FCMWAxes;
                app.FCMWLine = animatedline(ax2, 'Color', 'b');
                ylim(ax2, [0 1]); % Y축 범위 0~1 설정
                ylim(ax2, 'manual'); % [추가] Y축 범위 고정!
                % ... (title, labels, grid) ...

                % 데이터 히스토리 초기화 (동일)
                app.TimeHistory = [];
                app.PositionErrorHistory = [];
                app.FCMWHistory = [];
                app.SimulationStartTime = []; % 시작 시간 초기화 추가

                disp('Real-time plots initialized.');
            catch ME_plot
                uialert(app.UIFigure, ['Failed to initialize plots: ' ME_plot.message], 'UI Error');
            end


            setUI_Initial(app);
        end

        % Value changed function: SimulationModeDropDown
        function SimulationModeDropDownValueChanged(app, event)
            setUI_Initial(app);
        end

        % Value changed function: EnableCheckBox
        function EnableCheckBoxValueChanged(app, event)
            sim_mode = app.SimulationModeDropDown.Value;
            app.DisableCheckBox.Value = 0;

            if strcmp(sim_mode, 'Single Simulation')

            realloc_able = 101;
            setenv('REALLOC_ABLE', num2str(realloc_able));
            elseif strcmp(sim_mode, 'Automation')
                if event.Value == true
                    app.DisableCheckBox.Value = 0;
                end
            end

            
        end

        % Value changed function: DisableCheckBox
        function DisableCheckBoxValueChanged(app, event)
            sim_mode = app.SimulationModeDropDown.Value;
            app.EnableCheckBox.Value = 0;
            
            if strcmp(sim_mode, 'Single Simulation')

                realloc_able = 102;
                setenv('REALLOC_ABLE', num2str(realloc_able));
            elseif strcmp(sim_mode, 'Automation')
                if event.Value == true
                    app.EnableCheckBox.Value = 0; 
                end
            end
        end

        % Value changed function: StartQuitButton
        function StartQuitButtonValueChanged(app, event)
            sim_mode = app.SimulationModeDropDown.Value;

            if app.StartQuitButton.Value == true
                disp('Start Simulation...');
                app.StartQuitButton.Text = 'Quit';

                drawnow;

                Setpoint_Setenv(app);

                sim_mode = app.SimulationModeDropDown.Value;
                if strcmp(sim_mode, 'Single Simulation')
                    SetupTab_Off(app);

                    script_full_path = app.start_script_path;

                    ros_setup_cmd = 'source /opt/ros/noetic/setup.bash && source ~/catkin_ws/devel/setup.bash && ';
                    script_exec_cmd = sprintf('"%s"', script_full_path); 
                    terminal_cmd = sprintf('gnome-terminal -- bash -c "%s %s; exec bash"', ros_setup_cmd, script_exec_cmd);

                    disp('Opening new terminal to run environment setup script...');
                    [status, cmdout] = system(terminal_cmd);

                    if status ~= 0
                        error_msg = sprintf('Failed to open terminal or run script.\nOutput:\n%s', cmdout);
                        uialert(app.UIFigure, error_msg, 'Terminal Error');
                        app.StartQuitButton.Value = false;
                        app.StartQuitButton.Text = 'Start';
                        setUI_Initial(app);
                        return; 
                    end
                    disp('New terminal launched.');

                    flagFile = '/tmp/mavros_script_finished.flag';
                    if isfile(flagFile)
                        delete(flagFile);
                    end

                    disp('Waiting for arming...');
                    timeoutSeconds = 40;
                    checkInterval = 2;
                    isReady = false;
                    startTime = tic;

                    while toc(startTime) < timeoutSeconds
                        if isfile(flagFile)
                            disp('Flag file found! Proceeding...');
                            delete(flagFile); 
                            isReady = true;
                            break; 
                        end
                        
                        disp(['Waiting... (' num2str(round(toc(startTime))) 's / ' num2str(timeoutSeconds) 's)']);
                        pause(checkInterval);
                    end
                    if isReady
                        % Execute Simulink
                        try
                            disp('Starting Simulink model...');
                            open_system(app.model_path);
                            set_param(app.SimulinkModel, 'StopTime', 'inf');
                            set_param(app.SimulinkModel, 'SimulationCommand', 'start');

                            SetInitialWindParams(app);

                            disp('Simulink model started.');

                            app.SimulationStartTime = tic;

                            % Able Monitoring
                            MonitorTab_On(app);

                            app.WindInjectionPanel.Enable = 'on';
                            app.FaultInjectionPanel.Enable = 'on';
                            Wind_SingleSim(app);


                        catch ME
                            uialert(app.UIFigure, ME.message, 'Simulink Error');
                        end
                    else
                        error_msg = sprintf('Flag file did not appear within %d seconds.', timeoutSeconds);
                        disp(error_msg);
                        uialert(app.UIFigure, error_msg, 'Script Timeout');
                        app.StartQuitButton.Value = false;
                        app.StartQuitButton.Text = 'Start';
                        setUI_Initial(app);
                    end


                        %%% -------- NEED REVISE -------


                elseif strcmp(sim_mode, 'Automation')
                    SetupTab_Off(app);
                    MonitorTab_On(app);
                    app.SimulationStartTime = tic;

                    assignin('base', 'GUI_flag', true);

                    passAutomationParamsToWorkspace(app);

                    try
                        evalin('base', 'clear(''STOP_AUTOMATION_FLAG'')');
                    catch
                    end

                    % Execute .m file
                    try
                        original_dir = pwd;
                        cd(app.MatlabFolder); 
                        disp(['Running Automation m-file in: ', app.MatlabFolder]);


                        evalin('base', 'katech_automation');
                        
                        cd(original_dir);
                    catch ME
                        cd(original_dir);
                        error_msg = sprintf('Error running m-file:\n%s', ME.message);
                        uialert(app.UIFigure, error_msg, 'Script Error');

                    end
                    

                    disp('Automation script execution finished. Running cleanup.');
                    runCleanupLogic(app); 
                    app.StartQuitButton.Text = 'Start';
                    app.StartQuitButton.Value = false; % 버튼 상태 강제 복원
                    app.StartQuitButton.Enable = 'on'; % 버튼 활성화
                end


            else
                if strcmp(sim_mode, 'Single Simulation')
                    disp('Stopping Single Simulation...');

                    off_Simulink(app);
                    motor_button_init(app);

                    runCleanupLogic(app);
                    app.StartQuitButton.Text = 'Start';
                    
                elseif strcmp(sim_mode, 'Automation')
                    disp('Quit button pressed. Setting STOP_AUTOMATION_FLAG.');
                    disp('Script will stop at the end of the current loop step.');
                    
                    assignin('base', 'STOP_AUTOMATION_FLAG', 1);

                    try
                        evalin('base', 'clear(''GUI_flag'')');
                    catch
                    end
                    app.StartQuitButton.Text = 'Stopping...';
                    app.StartQuitButton.Enable = 'off';

                    runCleanupLogic(app);
                    off_Simulink(app);
                    
                end
            end
        end

        % Value changed function: WindDropDown
        function WindDropDownValueChanged(app, event)
            sim_mode = app.SimulationModeDropDown.Value;

            if strcmp(sim_mode, 'Single Simulation')
                Wind_SingleSim(app);
            elseif strcmp(sim_mode, 'Automation')
                Wind_Automation(app);
            end
        end

        % Value changed function: M1Button, M2Button, M3Button, M4Button, 
        % ...and 4 other components
        function M2ButtonValueChanged(app, event)
            MotorButtonValueChanged(app)
        end

        % Value changed function: FaultInjection_EnterButton
        function FaultInjection_EnterButtonValueChanged(app, event)
            sim_mode = app.SimulationModeDropDown.Value;

            if strcmp(sim_mode, 'Single Simulation')
                currentlySelected = app.SelectedFaultMotors;
                alreadyInjected = app.InjectedFaultMotors;
                motorsToInjectNow = setdiff(currentlySelected, alreadyInjected);

                if ~isempty(motorsToInjectNow)
                    disp(['Injecting NEW faults for motors: [', num2str(motorsToInjectNow), ']']);

                    motorButtons = {app.M1Button, app.M2Button, app.M3Button, app.M4Button, ...
                        app.M5Button, app.M6Button, app.M7Button, app.M8Button};
                    for i = 1:length(motorButtons)
                        motorNum = str2double(motorButtons{i}.Text(2:end));
                        if ismember(motorNum, motorsToInjectNow) && motorButtons{i}.Value
                            motorButtons{i}.BackgroundColor = app.FaultInjectedColor;
                        end
                    end
                    app.InjectedFaultMotors = union(alreadyInjected, motorsToInjectNow);
                    fprintf('Updated Injected Fault Motors (optimistically): [%s]\n', num2str(app.InjectedFaultMotors));
                    
                    drawnow;

                    success_count = 0;
                    failed_motors = []; 
                    for i = 1:length(motorsToInjectNow)
                        motor_num = motorsToInjectNow(i);
                        ros_setup_cmd = 'source /opt/ros/noetic/setup.bash && source ~/catkin_ws/devel/setup.bash && ';
                        pub_cmd = sprintf('rostopic pub /mavros/keyboard_command/failure_number std_msgs/Float32 "data: %.1f" --once', double(motor_num));
                        cmd = [ros_setup_cmd, pub_cmd];
                        [status, ~] = system(cmd);

                        if status == 0
                            disp(['Successfully published fault for motor ', num2str(motor_num)]);
                            success_count = success_count + 1;
                        else
                            warning(['Failed to publish fault for motor ', num2str(motor_num)]);
                            failed_motors(end+1) = motor_num; 
                        end
                    end

                    if ~isempty(failed_motors)
                        error_msg = sprintf('Failed to publish faults for motors: [%s]. Reverting state.', num2str(failed_motors));
                        uialert(app.UIFigure, error_msg, 'ROS Publish Error');

                        app.InjectedFaultMotors = setdiff(app.InjectedFaultMotors, failed_motors);

                        for i = 1:length(motorButtons)
                            motorNum = str2double(motorButtons{i}.Text(2:end));
                            if ismember(motorNum, failed_motors)
                                if motorButtons{i}.Value 
                                    motorButtons{i}.BackgroundColor = [1 0 0];
                                else 
                                    motorButtons{i}.BackgroundColor = app.MotorButtonOriginalColor;
                                end
                            end
                        end
                        disp(['Reverted Injected Fault Motors: [', num2str(app.InjectedFaultMotors), ']']);
                    end
                    % ---------------------------------------

                else
                    disp('Fault Injection Enter ignored: No new motors selected.');
                end
            end
        end

        % Value changed function: FaultInjection_ResetButton
        function FaultInjection_ResetButtonValueChanged(app, event)
            sim_mode = app.SimulationModeDropDown.Value;
            
            disp('Resetting all motor fault selections ...');
            
            motorButtons = {app.M1Button, app.M2Button, app.M3Button, app.M4Button, ...
                app.M5Button, app.M6Button, app.M7Button, app.M8Button};
            originalColor = app.MotorButtonOriginalColor;
            if ~(isnumeric(originalColor) && isequal(size(originalColor), [1 3]) && all(originalColor >= 0 & originalColor <= 1))
                originalColor = [0.94 0.94 0.94];
            end
            for i = 1:length(motorButtons)
                motorButtons{i}.Value = 0;
                motorButtons{i}.BackgroundColor = originalColor;
            end
            app.SelectedFaultMotors = [];
            app.InjectedFaultMotors = [];
            disp('Motor button UI and fault states reset visually.');
            drawnow;

            if strcmp(sim_mode, 'Single Simulation')
                disp('Sending fault reset command (0.0) via ROS...');
                ros_setup_cmd = 'source /opt/ros/noetic/setup.bash && source ~/catkin_ws/devel/setup.bash && ';
                pub_cmd = 'rostopic pub /mavros/keyboard_command/failure_number std_msgs/Float32 "data: 0.0" --once';
                cmd = [ros_setup_cmd, pub_cmd];
                [status, cmdout] = system(cmd);
                if status == 0
                    disp('Successfully published fault reset command (0.0).');
                else
                    error_msg = sprintf('Failed to publish fault reset command (0.0).\nOutput:\n%s', cmdout);
                    uialert(app.UIFigure, error_msg, 'ROS Publish Error');
                end
            end
        end

        % Value changed function: WindInjection_EnterButton_Single
        function WindInjection_EnterButton_SingleValueChanged(app, event)
            disp('--- WindInjection_EnterButtonPushed function CALLED! ---'); % [디버그 1] 함수 호출 확인

            sim_mode = app.SimulationModeDropDown.Value;
            wind_mode = app.WindDropDown.Value;

            fprintf('DEBUG: Current Sim Mode = %s, Wind Mode = %s\n', sim_mode, wind_mode); % [디버그 2] 모드 값 확인


            modelName = app.SimulinkModel; % 'data_collection'

            % --- 1. Single Simulation 모드일 때만 실행 ---
            if strcmp(sim_mode, 'Single Simulation')

                disp('DEBUG: Entered Single Simulation block.'); % [디버그 3] 조건문 진입 확인

                try % set_param 에러 대비
                    if strcmp(wind_mode, 'Constant Wind')

                        disp('Setting Constant Wind parameters via set_param...'); % 원래 메시지

                        % --- [Constant Wind 로직 using set_param] ---
                        disp('Setting Constant Wind parameters via set_param...');

                        meanX = app.MeanEditField_X.Value;
                        meanY = app.MeanEditField_Y.Value;
                        % meanZ = app.MeanEditField_Z.Value; 

                        % Simulink 파라미터 설정 (제공해주신 경로 사용)
                        set_param([modelName '/wind_toggle'], 'Value', '1'); 
                        set_param([modelName '/Switch Case Action Subsystem/wind_x2'], 'Value', num2str(meanX)); 
                        set_param([modelName '/Switch Case Action Subsystem/wind_y2'], 'Value', num2str(meanY)); 

                        disp('Constant Wind parameters set.');

                    elseif strcmp(wind_mode, 'Turbulence Wind')
                        % --- [Turbulence Wind 로직 using set_param] ---
                        disp('Setting Turbulence Wind parameters via set_param...');

                        input_wind_x = app.MeanEditField_X.Value;
                        input_wind_y = app.MeanEditField_Y.Value;

                        wind_normal_distribution_Max = app.MaxOffsetEditField_X.Value; 
                        wind_normal_distribution_Variance = app.VarianceEditField_X.Value; 

                        wind_normal_distribution_Direction_X = sign(input_wind_x);
                        wind_normal_distribution_Direction_Y = sign(input_wind_y);
                        wind_normal_distribution_X_Mean = abs(input_wind_x);
                        wind_normal_distribution_X_Max = wind_normal_distribution_X_Mean + wind_normal_distribution_Max;
                        wind_normal_distribution_X_Variance = wind_normal_distribution_Variance;
                        wind_normal_distribution_Y_Mean = abs(input_wind_y);
                        wind_normal_distribution_Y_Max = wind_normal_distribution_Y_Mean + wind_normal_distribution_Max;
                        wind_normal_distribution_Y_Variance = wind_normal_distribution_Variance;

                        set_param([modelName '/wind_toggle'], 'Value', '2'); % wind_type_toggle = 2
                        set_param([modelName '/Switch Case Action Subsystem1/ND_X_Mean'], 'Value', num2str(wind_normal_distribution_X_Mean));
                        set_param([modelName '/Switch Case Action Subsystem1/ND_X_Max'], 'Value', num2str(wind_normal_distribution_X_Max));
                        set_param([modelName '/Switch Case Action Subsystem1/ND_X_Variance'], 'Value', num2str(wind_normal_distribution_X_Variance));
                        set_param([modelName '/Switch Case Action Subsystem1/ND_Y_Mean'], 'Value', num2str(wind_normal_distribution_Y_Mean));
                        set_param([modelName '/Switch Case Action Subsystem1/ND_Y_Max'], 'Value', num2str(wind_normal_distribution_Y_Max));
                        set_param([modelName '/Switch Case Action Subsystem1/ND_Y_Variance'], 'Value', num2str(wind_normal_distribution_Y_Variance));
                        set_param([modelName '/Switch Case Action Subsystem1/ND_Dir_X'], 'Value', num2str(wind_normal_distribution_Direction_X));
                        set_param([modelName '/Switch Case Action Subsystem1/ND_Dir_Y'], 'Value', num2str(wind_normal_distribution_Direction_Y));
                        set_param([modelName '/Switch Case Action Subsystem1/ND_Dir_Z'], 'Value', '0'); % Z 방향은 0 고정

                        disp('Turbulence Wind parameters set.');

                    end 
                catch ME_setparam
                    error_msg = sprintf('Failed to set Simulink parameters.\nError: %s\nPlease ensure model is running and parameter paths are correct.', ME_setparam.message);
                    uialert(app.UIFigure, error_msg, 'Simulink Set Parameter Error');
                end

            end % --- if Single Sim 끝 ---
        end

        % Value changed function: WindInjection_ResetButton
        function WindInjection_ResetButtonValueChanged(app, event)
            sim_mode = app.SimulationModeDropDown.Value;
            modelName = app.SimulinkModel;

            if strcmp(sim_mode, 'Single Simulation')
                disp('Resetting Wind parameters to zero via set_param...');
                try
                    % --- [수정] Simulink 파라미터 리셋(Single Sim 전용)은 if문 안에 둡니다. ---
                    set_param([modelName '/Switch Case Action Subsystem/wind_x2'], 'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem/wind_y2'], 'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_X_Mean'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_X_Max'], 'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_X_Variance'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Y_Mean'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Y_Max'], 'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Y_Variance'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Dir_X'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Dir_Y'],'Value', '0');
                    set_param([modelName '/Switch Case Action Subsystem1/ND_Dir_Z'],'Value', '0');
                    disp('Simulink Wind parameters reset to zero.');
                catch ME_setparam
                    error_msg = sprintf('Failed to reset Simulink wind parameters.\nError: %s', ME_setparam.message);
                    uialert(app.UIFigure, error_msg, 'Simulink Set Parameter Error');
                end
            end 

            try
                % SingleSimWindPanel
                app.MeanEditField_X.Value = 0;
                app.MeanEditField_Y.Value = 0;
                app.MeanEditField_Z.Value = 0;
                app.VarianceEditField_X.Value = 0;
                app.VarianceEditField_Y.Value = 0;
                app.VarianceEditField_Z.Value = 0;
                app.MaxOffsetEditField_X.Value = 0;
                app.MaxOffsetEditField_Y.Value = 0;
                app.MaxOffsetEditField_Z.Value = 0;
                
                % AutomationWindPanel 
                if isprop(app, 'AutoMaxWindField')
                    app.AutoMaxWindField.Value = 0;
                    app.AutoVarianceField.Value = 0;
                    app.AutoMaxOffsetField.Value = 0;
                end
                disp('GUI Wind fields reset to zero.');
            catch ME_gui_reset
                 warning('Error resetting GUI Wind fields: %s', ME_gui_reset.message);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disp('Shutting down all session...');

            try
                motor_button_init(app);
                off_Simulink(app);
                runCleanupLogic(app);
            catch ME_cleanup
                disp(['Error during cleanup: ', ME_cleanup.message]);
            end
            % ------------------------------------

            % 2. 타이머 중지
            if ~isempty(app.UpdateTimer) && isvalid(app.UpdateTimer)
                try
                    stop(app.UpdateTimer);
                    delete(app.UpdateTimer);
                catch ME_timer
                    disp(['Error stopping/deleting timer: ', ME_timer.message]);
                end
            end
            
            % 3. ROS 노드 종료
            try
                rosshutdown;
                disp('ROS network shutdown.');
            catch ME_ros
                disp(['Error shutting down ROS: ', ME_ros.message]);
            end
            
            % 4. 앱 삭제 (기존 코드)
            kill_roscore(app);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AAMFlightReliabilityAssessmentSimulatorLabel = uilabel(app.UIFigure);
            app.AAMFlightReliabilityAssessmentSimulatorLabel.Text = 'AAM Flight Reliability Assessment Simulator';
            app.KATECH_Logo = uiimage(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimualtionSetupTab = uitab(app.TabGroup);
            app.SimualtionSetupTab.Title = 'Simualtion Setup';
            app.SimulationModeDropDownLabel = uilabel(app.SimualtionSetupTab);
            app.SimulationModeDropDownLabel.Text = 'Simulation Mode';
            app.SetpointPanel = uipanel(app.SimualtionSetupTab);
            app.SetpointPanel.Title = 'Setpoint';
            app.PositionLabel = uilabel(app.SetpointPanel);
            app.PositionLabel.Text = 'Position';
            app.XEditFieldLabel = uilabel(app.SetpointPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField_Position = uieditfield(app.SetpointPanel, 'numeric');
            app.XEditField_Position.ValueDisplayFormat = '%.1f';
            app.YEditFieldLabel = uilabel(app.SetpointPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField_Position = uieditfield(app.SetpointPanel, 'numeric');
            app.YEditField_Position.ValueDisplayFormat = '%.1f';
            app.ZEditFieldLabel = uilabel(app.SetpointPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField_Position = uieditfield(app.SetpointPanel, 'numeric');
            app.ZEditField_Position.ValueDisplayFormat = '%.1f';
            app.OrientationLabel = uilabel(app.SetpointPanel);
            app.OrientationLabel.Text = 'Orientation';
            app.XEditField_2Label = uilabel(app.SetpointPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_Orientation = uieditfield(app.SetpointPanel, 'numeric');
            app.XEditField_Orientation.ValueDisplayFormat = '%.1f';
            app.YEditField_2Label = uilabel(app.SetpointPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_Orientation = uieditfield(app.SetpointPanel, 'numeric');
            app.YEditField_Orientation.ValueDisplayFormat = '%.1f';
            app.ZEditField_2Label = uilabel(app.SetpointPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_Orientation = uieditfield(app.SetpointPanel, 'numeric');
            app.ZEditField_Orientation.ValueDisplayFormat = '%.1f';
            app.WEditFieldLabel = uilabel(app.SetpointPanel);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField_Orientation = uieditfield(app.SetpointPanel, 'numeric');
            app.WEditField_Orientation.ValueDisplayFormat = '%.1f';
            app.ReallocationEnablePanel = uipanel(app.SimualtionSetupTab);
            app.ReallocationEnablePanel.Title = 'Reallocation Enable';
            app.DisableCheckBox = uicheckbox(app.ReallocationEnablePanel);
            app.DisableCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisableCheckBoxValueChanged, true);
            app.DisableCheckBox.Text = 'Disable';
            app.EnableCheckBox = uicheckbox(app.ReallocationEnablePanel);
            app.EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBoxValueChanged, true);
            app.EnableCheckBox.Text = 'Enable';
            app.FaultInjectionPanel = uipanel(app.SimualtionSetupTab);
            app.FaultInjectionPanel.Title = 'Fault Injection';
            app.Octo_H = uiimage(app.FaultInjectionPanel);
            app.M1Button = uibutton(app.FaultInjectionPanel, 'state');
            app.M1Button.ValueChangedFcn = createCallbackFcn(app, @M2ButtonValueChanged, true);
            app.M1Button.Text = 'M1';
            app.M3Button = uibutton(app.FaultInjectionPanel, 'state');
            app.M3Button.ValueChangedFcn = createCallbackFcn(app, @M2ButtonValueChanged, true);
            app.M3Button.Text = 'M3';
            app.M8Button = uibutton(app.FaultInjectionPanel, 'state');
            app.M8Button.ValueChangedFcn = createCallbackFcn(app, @M2ButtonValueChanged, true);
            app.M8Button.Text = 'M8';
            app.M4Button = uibutton(app.FaultInjectionPanel, 'state');
            app.M4Button.ValueChangedFcn = createCallbackFcn(app, @M2ButtonValueChanged, true);
            app.M4Button.Text = 'M4';
            app.M5Button = uibutton(app.FaultInjectionPanel, 'state');
            app.M5Button.ValueChangedFcn = createCallbackFcn(app, @M2ButtonValueChanged, true);
            app.M5Button.Text = 'M5';
            app.M7Button = uibutton(app.FaultInjectionPanel, 'state');
            app.M7Button.ValueChangedFcn = createCallbackFcn(app, @M2ButtonValueChanged, true);
            app.M7Button.Text = 'M7';
            app.M6Button = uibutton(app.FaultInjectionPanel, 'state');
            app.M6Button.ValueChangedFcn = createCallbackFcn(app, @M2ButtonValueChanged, true);
            app.M6Button.Text = 'M6';
            app.M2Button = uibutton(app.FaultInjectionPanel, 'state');
            app.M2Button.ValueChangedFcn = createCallbackFcn(app, @M2ButtonValueChanged, true);
            app.M2Button.Text = 'M2';
            app.FaultInjection_EnterButton = uibutton(app.FaultInjectionPanel, 'state');
            app.FaultInjection_EnterButton.ValueChangedFcn = createCallbackFcn(app, @FaultInjection_EnterButtonValueChanged, true);
            app.FaultInjection_EnterButton.Text = 'Enter';
            app.FaultInjection_ResetButton = uibutton(app.FaultInjectionPanel, 'state');
            app.FaultInjection_ResetButton.ValueChangedFcn = createCallbackFcn(app, @FaultInjection_ResetButtonValueChanged, true);
            app.FaultInjection_ResetButton.Text = 'Reset';
            app.WindInjectionPanel = uipanel(app.SimualtionSetupTab);
            app.WindInjectionPanel.Title = 'Wind Injection';
            app.WindDropDown = uidropdown(app.WindInjectionPanel);
            app.WindDropDown.Items = {'Constant Wind', 'Turbulence Wind'};
            app.WindDropDown.ValueChangedFcn = createCallbackFcn(app, @WindDropDownValueChanged, true);
            app.WindDropDown.Value = 'Constant Wind';
            app.SingleSimWindPanel = uipanel(app.WindInjectionPanel);
            app.MeanLabel_Z = uilabel(app.SingleSimWindPanel);
            app.MeanLabel_Z.Text = 'Z';
            app.MaxOffsetLabel_Z = uilabel(app.SingleSimWindPanel);
            app.MaxOffsetLabel_Z.Text = 'Z';
            app.VarianceLabel_Z = uilabel(app.SingleSimWindPanel);
            app.VarianceLabel_Z.Text = 'Z';
            app.MeanEditFieldLabel = uilabel(app.SingleSimWindPanel);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField_Z = uieditfield(app.SingleSimWindPanel, 'numeric');
            app.MeanEditField_Z.ValueDisplayFormat = '%.1f';
            app.MeanEditField_X = uieditfield(app.SingleSimWindPanel, 'numeric');
            app.MeanEditField_X.ValueDisplayFormat = '%.1f';
            app.MeanEditFieldLabel_2 = uilabel(app.SingleSimWindPanel);
            app.MeanEditFieldLabel_2.Text = 'X';
            app.MeanEditField_Y = uieditfield(app.SingleSimWindPanel, 'numeric');
            app.MeanEditField_Y.ValueDisplayFormat = '%.1f';
            app.MeanEditFieldLabel_3 = uilabel(app.SingleSimWindPanel);
            app.MeanEditFieldLabel_3.Text = 'Y';
            app.MaxOffsetEditFieldLabel = uilabel(app.SingleSimWindPanel);
            app.MaxOffsetEditFieldLabel.Text = 'Max Offset';
            app.MaxOffsetEditField_Z = uieditfield(app.SingleSimWindPanel, 'numeric');
            app.MaxOffsetEditField_Z.ValueDisplayFormat = '%.1f';
            app.MaxOffsetEditField_X = uieditfield(app.SingleSimWindPanel, 'numeric');
            app.MaxOffsetEditField_X.ValueDisplayFormat = '%.1f';
            app.MeanEditFieldLabel_4 = uilabel(app.SingleSimWindPanel);
            app.MeanEditFieldLabel_4.Text = 'X';
            app.MaxOffsetEditField_Y = uieditfield(app.SingleSimWindPanel, 'numeric');
            app.MaxOffsetEditField_Y.ValueDisplayFormat = '%.1f';
            app.MeanEditFieldLabel_5 = uilabel(app.SingleSimWindPanel);
            app.MeanEditFieldLabel_5.Text = 'Y';
            app.VarianceEditFieldLabel = uilabel(app.SingleSimWindPanel);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField_Z = uieditfield(app.SingleSimWindPanel, 'numeric');
            app.VarianceEditField_Z.ValueDisplayFormat = '%.1f';
            app.VarianceEditField_X = uieditfield(app.SingleSimWindPanel, 'numeric');
            app.VarianceEditField_X.ValueDisplayFormat = '%.1f';
            app.MeanEditFieldLabel_6 = uilabel(app.SingleSimWindPanel);
            app.MeanEditFieldLabel_6.Text = 'X';
            app.VarianceEditField_Y = uieditfield(app.SingleSimWindPanel, 'numeric');
            app.VarianceEditField_Y.ValueDisplayFormat = '%.1f';
            app.MeanEditFieldLabel_7 = uilabel(app.SingleSimWindPanel);
            app.MeanEditFieldLabel_7.Text = 'Y';
            app.WindInjection_EnterButton_Single = uibutton(app.SingleSimWindPanel, 'state');
            app.WindInjection_EnterButton_Single.ValueChangedFcn = createCallbackFcn(app, @WindInjection_EnterButton_SingleValueChanged, true);
            app.WindInjection_EnterButton_Single.Text = 'Enter';
            app.WindInjection_ResetButton = uibutton(app.WindInjectionPanel, 'state');
            app.WindInjection_ResetButton.ValueChangedFcn = createCallbackFcn(app, @WindInjection_ResetButtonValueChanged, true);
            app.WindInjection_ResetButton.Text = 'Reset';
            app.SimulationModeDropDown = uidropdown(app.SimualtionSetupTab);
            app.SimulationModeDropDown.Items = {'Single Simulation', 'Automation'};
            app.SimulationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationModeDropDownValueChanged, true);
            app.SimulationModeDropDown.Value = 'Single Simulation';
            app.StartQuitButton = uibutton(app.SimualtionSetupTab, 'state');
            app.StartQuitButton.ValueChangedFcn = createCallbackFcn(app, @StartQuitButtonValueChanged, true);
            app.StartQuitButton.Text = 'Start';
            app.AutomationWindPanel = uipanel(app.SimualtionSetupTab);
            app.MaxWindSpeedAbsEditFieldLabel = uilabel(app.AutomationWindPanel);
            app.MaxWindSpeedAbsEditFieldLabel.Text = 'Max Wind Speed(Abs)';
            app.AutoMaxWindField = uieditfield(app.AutomationWindPanel, 'numeric');
            app.AutoMaxWindField.ValueDisplayFormat = '%.0f';
            app.MaxOffsetEditFieldLabel_2 = uilabel(app.AutomationWindPanel);
            app.MaxOffsetEditFieldLabel_2.Text = 'Max Offset';
            app.AutoMaxOffsetField = uieditfield(app.AutomationWindPanel, 'numeric');
            app.AutoMaxOffsetField.ValueDisplayFormat = '%.0f';
            app.VarianceEditField_2Label = uilabel(app.AutomationWindPanel);
            app.VarianceEditField_2Label.Text = 'Variance';
            app.AutoVarianceField = uieditfield(app.AutomationWindPanel, 'numeric');
            app.AutoVarianceField.ValueDisplayFormat = '%.0f';
            app.SimulationMonitoringTab = uitab(app.TabGroup);
            app.SimulationMonitoringTab.Title = 'Simulation Monitoring';
            app.PositionErrorAxes = uiaxes(app.SimulationMonitoringTab);
            xlabel(app.PositionErrorAxes, 'Time')
            ylabel(app.PositionErrorAxes, 'Pos Error')
            app.FCMWAxes = uiaxes(app.SimulationMonitoringTab);
            xlabel(app.FCMWAxes, 'Time')
            ylabel(app.FCMWAxes, 'FCMW')
            app.FCMWAxes.YTick = [0 0.5 1];
            app.MotorOutputsPanel = uipanel(app.SimulationMonitoringTab);
            app.MotorOutputsPanel.Title = 'Motor Outputs';
            app.Motor5Axes = uiaxes(app.MotorOutputsPanel);
            title(app.Motor5Axes, 'M5')
            app.Motor5Axes.XTick = [];
            app.Motor5Axes.YTick = [0 1];
            app.Motor5Axes.ZTick = [0 0.5 1];
            app.Motor1Axes = uiaxes(app.MotorOutputsPanel);
            title(app.Motor1Axes, 'M1')
            app.Motor1Axes.XTick = [];
            app.Motor1Axes.YTick = [0 1];
            app.Motor1Axes.ZTick = [0 0.5 1];
            app.Motor3Axes = uiaxes(app.MotorOutputsPanel);
            title(app.Motor3Axes, 'M3')
            app.Motor3Axes.XTick = [];
            app.Motor3Axes.YTick = [0 1];
            app.Motor3Axes.ZTick = [0 0.5 1];
            app.Motor7Axes = uiaxes(app.MotorOutputsPanel);
            title(app.Motor7Axes, 'M7')
            app.Motor7Axes.XTick = [];
            app.Motor7Axes.YTick = [0 1];
            app.Motor7Axes.ZTick = [0 0.5 1];
            app.Motor6Axes = uiaxes(app.MotorOutputsPanel);
            title(app.Motor6Axes, 'M6')
            app.Motor6Axes.XTick = [];
            app.Motor6Axes.YTick = [0 1];
            app.Motor6Axes.ZTick = [0 0.5 1];
            app.Motor8Axes = uiaxes(app.MotorOutputsPanel);
            title(app.Motor8Axes, 'M8')
            app.Motor8Axes.XTick = [];
            app.Motor8Axes.YTick = [0 1];
            app.Motor8Axes.ZTick = [0 0.5 1];
            app.Motor4Axes = uiaxes(app.MotorOutputsPanel);
            title(app.Motor4Axes, 'M4')
            app.Motor4Axes.XTick = [];
            app.Motor4Axes.YTick = [0 1];
            app.Motor4Axes.ZTick = [0 0.5 1];
            app.Motor2Axes = uiaxes(app.MotorOutputsPanel);
            title(app.Motor2Axes, 'M2')
            app.Motor2Axes.XTick = [];
            app.Motor2Axes.YTick = [0 1];
            app.Motor2Axes.ZTick = [0 0.5 1];
            app.Octo_H_2 = uiimage(app.MotorOutputsPanel);
            app.MotorLabel = uilabel(app.MotorOutputsPanel);
            app.MotorLabel.Text = '';
            app.PositionErrorLabel = uilabel(app.SimulationMonitoringTab);
            app.PositionErrorLabel.Text = 'Position Error';
            app.FeasiblyControllableMetricLabel = uilabel(app.SimulationMonitoringTab);
            app.FeasiblyControllableMetricLabel.Text = 'Feasibly Controllable Metric';
            app.LocalPositionLabel = uilabel(app.SimulationMonitoringTab);
            app.LocalPositionLabel.Text = 'Local Position:';
            app.PositionLabel_Title = uilabel(app.SimulationMonitoringTab);
            app.PositionLabel_Title.Text = 'Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_SILS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end